classdef GeomorphicIndicesExtractApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        UITable                       matlab.ui.control.Table
        CalculateHCCheckbox           matlab.ui.control.CheckBox
        SelectButton_2                matlab.ui.control.Button
        SelectButton                  matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        OutputFolderText              matlab.ui.control.EditField
        OuputWorkspaceEditFieldLabel  matlab.ui.control.Label
        SelectedFileText              matlab.ui.control.EditField
        InputWorkspaceEditFieldLabel  matlab.ui.control.Label
        CalculateVFCheckbox           matlab.ui.control.CheckBox
        CalculateSLKCheckbox          matlab.ui.control.CheckBox
        CalculateReCheckbox           matlab.ui.control.CheckBox
        CalculateBSCheckbox           matlab.ui.control.CheckBox
        CalculateAFCheckbox           matlab.ui.control.CheckBox
        CalculateHICheckbox           matlab.ui.control.CheckBox
        Panel                         matlab.ui.container.Panel
        MapAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % 存储流域 ID 列表
        basins_id_list;
        % 存储计算结果的数据矩阵
        result_data;
        % 存储输入和输出工作空间路径
        input_workspace;
        output_workspace;
        % 存储颜色映射
        color_map;
        % 颜色映射的索引
        color_index;
        % 存储流域多边形信息
        basins_polygons;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            % 仅使用一个参数调用 uigetdir
            folder = uigetdir(pwd);
            if isequal(folder, 0)
                return;
            end
            app.input_workspace = folder;
            app.SelectedFileText.Value = folder;
        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)
            % 仅使用一个参数调用 uigetdir
            folder = uigetdir(pwd);
            if isequal(folder, 0)
                return;
            end
            app.output_workspace = folder;
            app.OutputFolderText.Value = folder;
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
         % 检查是否选择了输入和输出工作空间
            if isempty(app.input_workspace) || isempty(app.output_workspace)
                errordlg('Please select both input and output workspaces.', 'Error');
                return;
            end

            % 读取流域 ID 列表
            basins_id_list_file = fullfile(app.input_workspace, 'basins_id_list.txt');
            app.basins_id_list = load(basins_id_list_file)';

            % 定义变量名
            names = ["basin_id", "HI", "SLK", "Re", "AF", "BS", "VF", "Iat"];
            % 初始化结果存储
            app.result_data = [];
            % 初始化存储流域多边形信息
            app.basins_polygons = [];

            % 获取复选框状态
            calc_HI = app.CalculateHICheckbox.Value;
            calc_HC = app.CalculateHCCheckbox.Value;
            calc_SLK = app.CalculateSLKCheckbox.Value;
            calc_Re = app.CalculateReCheckbox.Value;
            calc_AF = app.CalculateAFCheckbox.Value;
            calc_BS = app.CalculateBSCheckbox.Value;
            calc_VF = app.CalculateVFCheckbox.Value;

            % 初始化画布
            if calc_HC
                hc = figure('Name', 'Hypsometric Curves');
                hold on;
                xlabel("Normalized Area (a/A)");
                ylabel("Normalized Elevation (h/H)");
                box on;
                legend('show');
                % 生成颜色序列
                num_basins = length(app.basins_id_list);
                app.color_map = lines(num_basins);
                app.color_index = 1;
            end

            for basin_id = app.basins_id_list
                dem_file_path = fullfile(app.input_workspace, "basins", strcat("basin_", num2str(basin_id)), strcat("basin_", num2str(basin_id), ".tif"));

                % 检查文件是否存在
                if ~isfile(dem_file_path)
                    error('DEM 文件路径无效：%s', dem_file_path);
                end

                % 加载 DEM
                dem = fillsinks(GRIDobj(dem_file_path));

                % 计算流向
                flow_direction = FLOWobj(dem);

                % 展示 DEM
                axes(app.MapAxes);
                hold(app.MapAxes, 'on');
                box(app.MapAxes, "on");
                basins = drainagebasins(flow_direction);
                basin_polygon = GRIDobj2polygon(basins);
                fill(app.MapAxes, basin_polygon.X, basin_polygon.Y, app.color_map(app.color_index, :));

                % 显示流域 ID
                disp("流域ID为: " + num2str(basin_id));
                threshold = inputdlg('请输入该流域启动河流所需的最小上游区域（单位为像素）个数：', 'Threshold Input', 1, {'10'});
                threshold = str2double(threshold{1});

                % 提取主干道
                main_stream = trunk(STREAMobj(flow_direction, 'minarea', threshold));
                [main_stream_x, main_stream_y] = STREAMobj2XY(main_stream);

                plot(app.MapAxes, main_stream_x, main_stream_y, 'Color', 'b', 'LineWidth', 5);
                scatter(app.MapAxes, main_stream_x(1), main_stream_y(1), 'MarkerFaceColor', 'r');
                scatter(app.MapAxes, main_stream_x(end-1), main_stream_y(end-1), 'MarkerFaceColor', 'g');

                % 初始化指数
                HI_index = NaN;
                SLK_index = NaN;
                Re_index = NaN;
                AF_index = NaN;
                BS_index = NaN;
                VF_index = NaN;

                % 计算选定的指数
                if calc_HI
                    HI_index = ttb_calculate_hi_index(dem);
                    basin_polygon.HI = double(HI_index);
                end
                if calc_SLK
                    SLK_index = ttb_calculate_slk_index(dem, main_stream);
                    basin_polygon.SLK = double(SLK_index);
                end
                if calc_Re
                    Re_index = ttb_calculate_re_index(dem, main_stream);
                    basin_polygon.Re = double(Re_index);
                end
                if calc_AF
                    AF_index = ttb_calculate_af_index(dem, main_stream, false);
                    basin_polygon.AF = double(AF_index);
                end
                if calc_BS
                    BS_index = ttb_calculate_bs_index(dem, main_stream, false);
                    basin_polygon.BS = double(BS_index);
                end
                if calc_VF
                    v_fw_min = inputdlg('请输入河道宽度最小值：', 'VF Min Width', 1, {'0'});
                    v_fw_min = str2double(v_fw_min{1});
                    v_fw_max = inputdlg('请输入河谷宽度最大值：', 'VF Max Width', 1, {'10'});
                    v_fw_max = str2double(v_fw_max{1});
                    nbin = inputdlg('请输入 VF 需要采集的点个数：', 'VF Point Count', 1, {'10'});
                    nbin = str2double(nbin{1});
                    VF_index = ttb_calculate_vf_index(dem, main_stream, v_fw_min, v_fw_max, nbin, false);
                    basin_polygon.VF = double(VF_index);
                end

                % 输出 HC plot
                if calc_HC
                    figure(hc);
                    nbin = inputdlg('请输入 HC 需要采集的点个数：', 'HC Point Count', 1, {'10'});
                    nbin = str2double(nbin{1});
                    [normalized_area, normalized_elevation] = hypscurve(dem, nbin);
                    normalized_elevation = normalize(normalized_elevation', 'range', [0, 1])';
                    normalized_area = normalize(normalized_area', 'range', [0, 1])';
                    plot(normalized_area, normalized_elevation, 'Color', app.color_map(app.color_index, :),...
                         'DisplayName', sprintf('Basin %d', basin_id));
                    app.color_index = app.color_index + 1;
                    figure(app.UIFigure);
                end

                % 动态计算 Iat
                Iat = 0;
                Iat_count = 0; % 记录参与计算的指标数量

                if calc_HI
                    Iat = Iat + (HI_index > 0.6) + 2 * (HI_index >= 0.35 && HI_index <= 0.6) + 3 * (HI_index < 0.35);
                    Iat_count = Iat_count + 1;
                end
                if calc_SLK
                    Iat = Iat + (SLK_index > 3.7) + 2 * (SLK_index >= 2.5 && SLK_index <= 3.7) + 3 * (SLK_index < 2.5);
                    Iat_count = Iat_count + 1;
                end
                if calc_Re
                    Iat = Iat + (Re_index < 0.5) + 2 * (Re_index >= 0.5 && Re_index <= 0.75) + 3 * (Re_index > 0.75);
                    Iat_count = Iat_count + 1;
                end
                if calc_AF
                    Iat = Iat + (AF_index <= 0.5) + 2 * (AF_index > 0.5 && AF_index < 1.0) + 3 * (AF_index >= 1.0);
                    Iat_count = Iat_count + 1;
                end
                if calc_BS
                    Iat = Iat + (BS_index > 2.3) + 2 * (BS_index >= 1.5 && BS_index <= 2.3) + 3 * (BS_index < 1.5);
                    Iat_count = Iat_count + 1;
                end
                if calc_VF
                    Iat = Iat + (VF_index <= 0.5) + 2 * (VF_index > 0.5 && VF_index < 1.0) + 3 * (VF_index >= 1.0);
                    Iat_count = Iat_count + 1;
                end

                if Iat_count > 0
                    Iat = Iat / Iat_count;
                    basin_polygon.Iat = double(Iat);
                end

                % 存储当前流域的多边形信息
                app.basins_polygons = [app.basins_polygons; basin_polygon]; 

                % 将计算结果存入数据矩阵
                app.result_data = [app.result_data; basin_id, HI_index, SLK_index, Re_index, AF_index, BS_index, VF_index, Iat];
            end

            % 将数据写入 Excel 文件
            result_table = array2table(app.result_data, 'VariableNames', names);
            table_file_path = fullfile(app.output_workspace, "indices.xlsx");
            writetable(result_table, table_file_path, 'WriteMode', 'replacefile');
            disp("结果已保存为 Excel 文件！");

            % 将流域多边形信息导出为 shapefile
            if ~isempty(app.basins_polygons)
                info = georasterinfo(dem_file_path);
                p = info.CoordinateReferenceSystem;
                wkt = wktstring(p, 'Version', "wkt1");
                fid = fopen(fullfile(app.output_workspace, "basins_polygons.prj"), "w");
                fprintf(fid, "%s", wkt);
                fclose(fid);
                % 假设你使用的是 shapewrite 函数，根据你的实际情况修改
                shapewrite(app.basins_polygons, fullfile(app.output_workspace, 'basins_polygons.shp')); 
            end

            % 设置统一图例与坐标轴标签
            if calc_HC
                figure(hc);
                legend('show');
                xlabel("Normalized Area (a/A)");
                ylabel("Normalized Elevation (h/H)");
                box on;
                hold off;
                saveas(hc, fullfile(app.output_workspace, "HC.png"));
            end

            % 更新 UITable 的数据
            app.UITable.Data = app.result_data;
            app.UITable.ColumnName = names;

            msgbox("计算成功！");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MapAxes = uiaxes(app.UIFigure);
            title(app.MapAxes, 'Map')
            xlabel(app.MapAxes, 'X')
            ylabel(app.MapAxes, 'Y')
            zlabel(app.MapAxes, 'Z')
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '地貌指数运算';
            app.CalculateHICheckbox = uicheckbox(app.UIFigure);
            app.CalculateHICheckbox.Text = 'HI';
            app.CalculateHICheckbox.Value = true;
            app.CalculateAFCheckbox = uicheckbox(app.UIFigure);
            app.CalculateAFCheckbox.Text = 'AF';
            app.CalculateAFCheckbox.Value = true;
            app.CalculateBSCheckbox = uicheckbox(app.UIFigure);
            app.CalculateBSCheckbox.Text = 'BS';
            app.CalculateBSCheckbox.Value = true;
            app.CalculateReCheckbox = uicheckbox(app.UIFigure);
            app.CalculateReCheckbox.Text = 'Re';
            app.CalculateReCheckbox.Value = true;
            app.CalculateSLKCheckbox = uicheckbox(app.UIFigure);
            app.CalculateSLKCheckbox.Text = 'SLK';
            app.CalculateSLKCheckbox.Value = true;
            app.CalculateVFCheckbox = uicheckbox(app.UIFigure);
            app.CalculateVFCheckbox.Text = 'VF';
            app.CalculateVFCheckbox.Value = true;
            app.InputWorkspaceEditFieldLabel = uilabel(app.UIFigure);
            app.InputWorkspaceEditFieldLabel.Text = 'Input Workspace';
            app.SelectedFileText = uieditfield(app.UIFigure, 'text');
            app.OuputWorkspaceEditFieldLabel = uilabel(app.UIFigure);
            app.OuputWorkspaceEditFieldLabel.Text = 'Ouput Workspace';
            app.OutputFolderText = uieditfield(app.UIFigure, 'text');
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.SelectButton = uibutton(app.UIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.SelectButton_2 = uibutton(app.UIFigure, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select';
            app.CalculateHCCheckbox = uicheckbox(app.UIFigure);
            app.CalculateHCCheckbox.Text = 'HC';
            app.CalculateHCCheckbox.Value = true;
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeomorphicIndicesExtractApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end